classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Configure acceptable graphic extensions
            fileFilters = {'*.png;*.jpg;*.jpeg;*.bmp;*.tif', 'Image Files (*.png, *.jpg, *.jpeg, *.bmp, *.tif)'};

            % Open user navigation window
            [filename, pathname] = uigetfile(fileFilters, 'Select an Image');

            % Verify a valid file was selected
            if ~isequal(filename,0) && ~isequal(pathname,0)
                % Assemble path and load numerical image data matrix
                imgData = imread(fullfile(pathname, filename));

                % Force show the graphic within the specific App UI Axes frame
                imshow(imgData, 'Parent', app.UIAxes);

                % Dynamically update the plot title to reflect the file name
                title(app.UIAxes, ['File Name: ' filename]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end